classdef olf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        PresenceLabel          matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        ModeLabel              matlab.ui.control.Label
        BPMLabel               matlab.ui.control.Label
        VITALSIGNMONITORLabel  matlab.ui.control.Label
        StopButton             matlab.ui.control.Button
        StartButton            matlab.ui.control.Button
        PhasePlot              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        running = false
        rx
        tx
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.running = true;

            % Radar parametreleri
            Fs = 70e3; Fc = 4.6e9; F0 = 300; T = 2;
            SamplesPerFrame = T * Fs; Repetation = 32; M = 1;

            app.rx = sdrrx('Pluto', 'CenterFrequency', Fc, 'BasebandSampleRate', Fs, ...
                'GainSource','Manual', 'Gain', 61, 'OutputDataType', 'double');
            app.tx = sdrtx('Pluto', 'CenterFrequency', Fc, 'BasebandSampleRate', Fs, 'Gain', 0);
            app.rx.SamplesPerFrame = SamplesPerFrame;

            t = (0:SamplesPerFrame-1)' / Fs;
            t_new = (0:(Repetation*SamplesPerFrame-1))' / Fs;
            tx_signal = exp(1j*2*pi*F0*t);
            app.tx.transmitRepeat(tx_signal);

            prev = zeros((Repetation-1)*SamplesPerFrame,1);
            bpm = 0; t_bpm = 0;
            t_plot = t_new - Repetation * SamplesPerFrame / Fs;
            t_plot1 = t_plot(1:M:end);
            Detect = 0; stop = 0; RRate = 0;
            ind = 0; 

            while app.running 
                ind = ind + 1;
                data = app.rx();
                data1 = [prev; data];
                prev = data1(SamplesPerFrame+1:end);
                phase = unwrap(angle(data1 .* exp(-1j*2*pi*F0*t_new)));
                phase1 = decimate(phase, M);
                m = movmean(phase1, 8000/M);
                [fenvu, ~] = envelope(phase1, 14000/M, 'peak');
                [pks, locs] = findpeaks(fenvu, "MinPeakProminence",0.1, "MinPeakDistance", Fs/M);
                t_new1 = t_new(1:M:end);

                if ind > 15
                    if length(locs(locs > T*Fs/M*Repetation - 30*Fs/M)) >= 1
                        if length(locs(locs>T*Fs/M*Repetation-30*Fs/M)) > 1
                            Detect = 1;

                            if 60/(RRate+0.1) < (t_plot1(end) - t_plot1(locs(end)))
                                locs1 = [locs; length(t_plot1)];

                            else
                                locs1 = locs;
                            end
                            RRate = 1/mean(diff(t_new1(locs1(locs1>T*Fs/M*Repetation-30*Fs/M))))*60;
                            presence = "Detected";
                            if stop >= 1 && stop < 10
                                mode = "Apneoa";
                                stop=0;
                            else
                                stop=0;
                                if RRate < 10
                                    mode = "Slow";
                                elseif RRate > 15
                                    mode = "Fast";
                                else
                                    mode = "Normal";
                                end
                                
                            end
                        else
                            Detect=1;
                            RRate = 0;
                            presence = "Detected";
                            if stop >= 1 && stop < 10
                                mode = "Apneoa";
                                stop=0;
                            else
                                mode = "-";
                                stop=0;
                            end
                        end
                    else
                        % NO PEAK IN 30 S
                        RRate = 0;
                        if Detect == 1
                            stop = stop + 1;
                        else
                            presence = "Undetected";
                            mode = "---";
                        end
                        if stop >= 10
                            presence = "Undetected";
                            mode = "---";
                            Detect = 0;
                        end
                    end
                else
                    mode = "Stabilizing...";
                    presence = "Waiting...";
                end

                % GUI güncelle
                % bpm = [bpm; RRate];
                % t_bpm = [t_bpm; t_bpm(end)+2];
                t_plot = t_plot + 2;
                t_plot1 = t_plot(1:M:end);

                % BPM ve Mode Label
                app.BPMLabel.Text = sprintf("BPM: %.0f", RRate);
                app.ModeLabel.Text = "Mode: " + mode;
                app.PresenceLabel.Text = "Presence: " + presence;

                % Plotları güncelle
                plot(app.PhasePlot, t_plot, phase);
                hold(app.PhasePlot, 'on');
                plot(app.PhasePlot, t_plot1(locs), pks, 'vg', 'MarkerFaceColor','g');
                hold(app.PhasePlot, 'off');
                xlabel(app.PhasePlot, 'Time (s)');
                ylabel(app.PhasePlot, 'Phase (rad)');
                title(app.PhasePlot, '')
                grid(app.PhasePlot, 'on');

                % plot(app.BPMPlot, t_bpm, bpm);
                % xlabel(app.BPMPlot, 'Time (s)');
                % ylabel(app.BPMPlot, 'BPM');
                % grid(app.BPMPlot, 'on');

                drawnow;
            end

            release(app.rx);
            release(app.tx);
            app.running = false;
        
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.running = false;

            if ~isempty(app.rx)
                release(app.rx);
            end
            if ~isempty(app.tx)
                release(app.tx);
            end
            
            app.BPMLabel.Text = 'BPM:---';
            app.ModeLabel.Text = 'Respiration Status:---';
            app.ModeLabel.FontColor = [0 0 0];
            app.PresenceLabel.Text = "Presence: ---";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PhasePlot = uiaxes(app.UIFigure);
            xlabel(app.PhasePlot, 'Time (s)')
            ylabel(app.PhasePlot, 'Phase (rad)')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.VITALSIGNMONITORLabel = uilabel(app.UIFigure);
            app.VITALSIGNMONITORLabel.Text = 'VITAL SIGN MONITOR';
            app.BPMLabel = uilabel(app.UIFigure);
            app.BPMLabel.Text = 'BPM';
            app.ModeLabel = uilabel(app.UIFigure);
            app.ModeLabel.Text = 'Mode';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo.jpg');
            app.PresenceLabel = uilabel(app.UIFigure);
            app.PresenceLabel.Text = 'Presence';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = olf

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end